classdef LightStim_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RandomCheckerboardIlluminationLabel  matlab.ui.control.Label
        FullfieldIlluminationLabel      matlab.ui.control.Label
        ClearCheckerboardHistoryButton  matlab.ui.control.Button
        LoadCheckerboardProtocolButton  matlab.ui.control.Button
        SaveCheckerboardProtocolButton  matlab.ui.control.Button
        RecordCheckerboardHistorySwitch  matlab.ui.control.Switch
        RecordInputHistoryLabel_2       matlab.ui.control.Label
        LoadFullfieldProtocolButton     matlab.ui.control.Button
        ClearFullfieldHistoryButton     matlab.ui.control.Button
        SaveFullfieldProtocolButton     matlab.ui.control.Button
        RecordFullfieldHistorySwitch    matlab.ui.control.Switch
        RecordInputHistoryLabel         matlab.ui.control.Label
        BackgroundColorDropDown         matlab.ui.control.DropDown
        BackgroundColorDropDownLabel    matlab.ui.control.Label
        RuntimeDropDown                 matlab.ui.control.DropDown
        FullfieldIlluminationOFFDropDown  matlab.ui.control.DropDown
        FullfieldIlluminationONDropDown  matlab.ui.control.DropDown
        DoubleClicktoSTOPButton         matlab.ui.control.Button
        CheckerboardSizenxnSpinner      matlab.ui.control.Spinner
        CheckerboardSizenxnSpinnerLabel  matlab.ui.control.Label
        FullfieldIlluminationHistory    matlab.ui.control.Table
        CheckerboardHistory             matlab.ui.control.Table
        NumberofSetsSpinner             matlab.ui.control.Spinner
        NumberofSetsSpinnerLabel        matlab.ui.control.Label
        FrequencyHzEditField            matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel       matlab.ui.control.Label
        RuntimeEditField                matlab.ui.control.NumericEditField
        RuntimeEditFieldLabel           matlab.ui.control.Label
        RunRandomCheckerboardIlluminationButton  matlab.ui.control.Button
        FullfieldColorDropDown          matlab.ui.control.DropDown
        FullfieldColorDropDownLabel     matlab.ui.control.Label
        FullfieldIlluminationOFFEditField  matlab.ui.control.EditField
        FullfieldIlluminationOFFEditFieldLabel  matlab.ui.control.Label
        FullfieldIlluminationONEditField  matlab.ui.control.EditField
        FullfieldIlluminationONEditFieldLabel  matlab.ui.control.Label
        FullfieldIlluminationRepetitionSpinner  matlab.ui.control.Spinner
        FullfieldIlluminationRepetitionSpinnerLabel  matlab.ui.control.Label
        RunFullfieldIlluminationButton  matlab.ui.control.Button
        OpenWindowButton                matlab.ui.control.Button
        ContextMenu                     matlab.ui.container.ContextMenu
        ApplySettingsMenu               matlab.ui.container.Menu
        DeleteRowMenu                   matlab.ui.container.Menu
        AddRowMenu                      matlab.ui.container.Menu
        ContextMenu2                    matlab.ui.container.ContextMenu
        ApplySettingsMenu_2             matlab.ui.container.Menu
        DeleteRowMenu_2                 matlab.ui.container.Menu
        AddRowMenu_2                    matlab.ui.container.Menu
    end

    properties (Access = private)
        %% Properties for default settings
        centeredRect
        window
        windowRect
        screenNumber
        white
        black
        %% Properties for stopping illumination
        stopFlag
        %% Properties for selected row on table
        selectedrow_fullfield
        selectedrow_checkerboard
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Default settings when app is launched
            sca;close all;clearvars;clc % Clear the workspace and the screen
            PsychDefaultSetup(2); % Here we call some default settings for setting up Psychtoolbox
            %% 
            app.stopFlag = false; % Illumination not paused
            %% Default input values (사실 없어도 디자인뷰에서 숫자를 직접 바꾸는 편이 빠름)
            % app.FullfieldIlluminationRepetitionSpinner.Value = 10; % full-field 자극반복횟수, 기본=10
            % app.FullfieldIlluminationONEditField.Value = 4; % full-field 자극 ON 시간, 기본 4 sec
            % app.FullfieldIlluminationOFFEditField.Value = 4; % full-field 자극 OFF 시간, 기본 4 sec
            % app.FullfieldColorDropDown.Value = "White"; % full-field 정사각형 색, 기본 White
            % app.BackgroundColorDropDown.Value = "Black"; % full-field 배경색, 기본 Black
            % app.RecordFullfieldHistorySwitch.Value = "On"; % full-field 자극 설정 기록, 기본 ON
            % 
            % app.CheckerboardSizenxnSpinner.Value = 8; % checkerboard 자극 배열 설정, 기본 8x8 배열 
            % app.RuntimeEditField.Value = 1; % checkerboard 자극 시간 설정, 기본 1 sec
            % app.RuntimeDropDown.Value = "(s)";
            % app.FrequencyHzEditField = "10"; % checkerboard 자극 frequency 설정, 기본 10 Hz
            % app.NumberofSetsSpinner.Value = 1; % checkerboard 세트 반복 횟수, 기본 1 set
            % app.RecordCheckerboardHistorySwitch.Value = "On"; % checkerboard 자극 설정 기록, 기본 ON
            %% Selected rows in table
            app.selectedrow_fullfield = 99;

            %% Number format
            format shortG
        end

        % Button pushed function: OpenWindowButton
        function OpenWindowButtonPushed(app, event)
            %% Open an on screen window & Open the serial port
            app.black = [0 0 0];
            screens=Screen('Screens'); % Get the screen numbers
            app.screenNumber=max(screens); % Select the maximum of these numbers
            app.white=WhiteIndex(app.screenNumber); % Define white
            app.black=BlackIndex(app.screenNumber); % Define black
            % s=serial('COM1'); % Find serial port
            % set(s,'BaudRate',57600);

            [app.window,app.windowRect]=PsychImaging('OpenWindow',app.screenNumber,app.black); % Open an on screen window and color it black
            [screenXpixels,screenYpixels]=Screen('WindowSize',app.window); % Get the size of the on screen window in pixels
            % fopen(s); % Open the serial port            

            %% Positions of Full-field illumination circle
            % Get the center coordinate of the window
            [xCenter, yCenter] = RectCenter(app.windowRect);
            % Make a base Rect of 200 by 200 pixels
            baseRect = [0 0 1200 1000];
            % Center the rectangle on the center of the screen
            app.centeredRect = CenterRectOnPointd(baseRect, xCenter, yCenter);
        end

        % Button pushed function: RunFullfieldIlluminationButton
        function RunFullfieldIlluminationButtonPushed(app, event)
            app.stopFlag = false; % unpause any paused illuminations
            %% Run full-field illumination
            %% Light stimulus protocol
            rep = app.FullfieldIlluminationRepetitionSpinner.Value; % 자극반복횟수
            visTime = app.FullfieldIlluminationONEditField.Value; % 자극 ON 시간, unit: sec
            invisTime = app.FullfieldIlluminationOFFEditField.Value; % 자극 OFF 시간, unit: sec

            % rectColor set based on user input
            if app.FullfieldColorDropDown.Value == "White"
                rectColor = [1 1 1];
            elseif app.FullfieldColorDropDown.Value == "Black"
                rectColor = [0 0 0];
            elseif app.FullfieldColorDropDown.Value == "Red"
                rectColor = [1 0 0];
            elseif app.FullfieldColorDropDown.Value == "Green"
                rectColor = [0 1 0];
            elseif app.FullfieldColorDropDown.Value == "Blue"
                rectColor = [0 0 1];
            end

            % Background windows color set based on user input
            if app.BackgroundColorDropDown.Value == "Black"
                WindowColor = [0 0 0];
                Screen('FillRect',app.window, WindowColor, app.windowRect);
            elseif app.BackgroundColorDropDown.Value == "White"
                WindowColor = [1 1 1];
                Screen('FillRect',app.window, WindowColor, app.windowRect);
            elseif app.BackgroundColorDropDown.Value == "Red"
                WindowColor = [1 0 0];
                Screen('FillRect',app.window, WindowColor, app.windowRect);
            elseif app.BackgroundColorDropDown.Value == "Green"
                WindowColor = [0 1 0];
                Screen('FillRect',app.window, WindowColor, app.windowRect);
            elseif app.BackgroundColorDropDown.Value == "Blue"
                WindowColor = [0 0 1];
                Screen('FillRect',app.window, WindowColor, app.windowRect);
            end
            Screen('Flip',app.window);
            
            %% Recording input settings on table
            % Record input values when switch is ON
            if app.RecordFullfieldHistorySwitch.Value == "On"
                % Get the user input from some GUI components (e.g., edit boxes)
                repetition = app.FullfieldIlluminationRepetitionSpinner.Value;
                if app.FullfieldIlluminationONDropDown.Value == "(s)"
                    timeON = sprintf('%s (s)',app.FullfieldIlluminationONEditField.Value);
                elseif app.FullfieldIlluminationONDropDown.Value == "(ms)"
                    timeON = sprintf('%s (ms)',app.FullfieldIlluminationONEditField.Value);
                elseif app.FullfieldIlluminationONDropDown.Value == "(μs)"
                    timeON = sprintf('%s (μs)',app.FullfieldIlluminationONEditField.Value);
                end
                if app.FullfieldIlluminationOFFDropDown.Value == "(s)"
                    timeOFF = sprintf('%s (s)',app.FullfieldIlluminationOFFEditField.Value);
                elseif app.FullfieldIlluminationOFFDropDown.Value == "(ms)"
                    timeOFF = sprintf('%s (ms)',app.FullfieldIlluminationOFFEditField.Value);
                elseif app.FullfieldIlluminationOFFDropDown.Value == "(μs)"
                    timeOFF = sprintf('%s (μs)',app.FullfieldIlluminationOFFEditField.Value);
                end
                color_rectangle = app.FullfieldColorDropDown.Value;
                color_background = app.BackgroundColorDropDown.Value;

                % Get the existing table data
                tableData = get(app.FullfieldIlluminationHistory, 'Data');

                % Append the new data to the existing table data
                newRow = {repetition, timeON, timeOFF, color_rectangle, color_background}; % Add more elements if needed for additional inputs
                newTableData = [tableData; newRow];

                % Update the table with the new data
                set(app.FullfieldIlluminationHistory, 'Data', newTableData);

            end % No recordings when switch is OFF
           
            %% Light stimulation
            for i=1:rep % Run illumination for "rep" number of repetitions
                drawnow; % Look for stopFlag = true
                if app.stopFlag == true % If STOP button pushed, loop will break and illumination will stop
                    break
                end
                drawnow;
                % Draw the dot to the screen
                Screen('FillRect', app.window, rectColor, app.centeredRect);
                Screen('Flip',app.window); % Display the rectangle on the screen
                % fwrite(s,0); % Sending a TTL pulse to MC_Card through RS232c
                
                % 자극 ON 시간 단위 (s, ms, us) 설정
                if app.FullfieldIlluminationONDropDown.Value == "(s)"
                    WaitSecs(str2double(visTime));  % Pause for visTime seconds
                elseif app.FullfieldIlluminationONDropDown.Value == "(ms)"
                    WaitSecs(str2double(visTime)*(10^-3));  % Pause for visTime milliseconds
                elseif app.FullfieldIlluminationONDropDown.Value == "(μs)"
                    WaitSecs(str2double(visTime)*(10^-6));  % Pause for visTime microseconds
                end
                Screen('Flip',app.window);

                % 자극 OFF 시간 단위 (s, ms, us) 설정
                if app.FullfieldIlluminationOFFDropDown.Value == "(s)"
                    WaitSecs(str2double(invisTime));
                elseif app.FullfieldIlluminationOFFDropDown.Value == "(ms)"
                    WaitSecs(str2double(invisTime)*(10^-3));
                elseif app.FullfieldIlluminationOFFDropDown.Value == "(μs)"
                    WaitSecs(str2double(invisTime)*(10^-6));
                end
            end
            Screen('FillRect',app.window, app.black, app.windowRect); % 자극 이후 배경색 검은색으로 복구
            Screen('Flip',app.window);
        end

        % Button pushed function: RunRandomCheckerboardIlluminationButton
        function RunRandomCheckerboardIlluminationButtonPushed(app, event)
            app.stopFlag = false; % unpause any paused illuminations
            %% Run pseudo-RandomCheckerboard illumination
            %% Positions of pseudo-RandomCheckerboard squares (Bernouli distribution)
            % Get the center coordinate of the window
            [xCenter, yCenter] = RectCenter(app.windowRect);
            
            % Number of rows and columns
            n = app.CheckerboardSizenxnSpinner.Value; % 자극 배열 설정
            dim = 1040/n; % Number of pixels per square = 1040/n
            % Make a base Rect of 200 by 200 pixels
            baseRect = [0 0 dim dim];
            g = n/2-0.5;
            % Make the coordinates for our grid of squares
            [xPos, yPos] = meshgrid(-g:1:g, -g:1:g);
            
            % Calculate the number of squares and reshape the matrices of coordinates into a vector
            [s1, s2] = size(xPos);
            numSquares = s1 * s2;
            xPos = reshape(xPos, 1, numSquares);
            yPos = reshape(yPos, 1, numSquares);
            
            % Scale the grid spacing to the size of our squares and center
            %xPosLeft = xPos .* dim + screenXpixels * 0.25;
            xPosLeft = xPos .* dim + xCenter;
            yPosLeft = yPos .* dim + yCenter;
            
            %xPosRight = xPos .* dim + screenXpixels * 0.75;
            %yPosRight = yPos .* dim + yCenter;
            
            % Make our rectangle coordinates
            allRectsLeft = nan(4, 3);
            %allRectsRight = nan(4, 3);
            for i = 1:numSquares
                allRectsLeft(:, i) = CenterRectOnPointd(baseRect,...
                    xPosLeft(i), yPosLeft(i));
                %allRectsRight(:, i) = CenterRectOnPointd(baseRect,...
                %    xPosRight(i), yPosRight(i));
            end
            %% Light stimulus protocol generation
            % 자극반복횟수 계산. (1 set, 10 Hz, 15 min)
            if app.RuntimeDropDown.Value == "(s)"
                rep = (app.FrequencyHzEditField.Value)*(app.RuntimeEditField.Value);
            elseif app.RuntimeDropDown.Value == "(min)"
                rep = (app.FrequencyHzEditField.Value)*(60)*(app.RuntimeEditField.Value);
            elseif app.RuntimeDropDown.Value == "(h)"
                rep = (app.FrequencyHzEditField.Value)*(60^2)*(app.RuntimeEditField.Value);
            end

            tot_rep = rep*(app.NumberofSetsSpinner.Value); % 패턴을 여러 세트 반복할 경우
            visTime = 1/(app.FrequencyHzEditField.Value); % n Hz
            invisTime = 0.03; % 자극 OFF 시간, unit: sec

            %% 자극 기록
            if app.RecordCheckerboardHistorySwitch.Value == "On"
                boardsize = sprintf('%dx%d',app.CheckerboardSizenxnSpinner.Value, app.CheckerboardSizenxnSpinner.Value); % 자극 배열 정보
                if app.RuntimeDropDown.Value == "(s)" % 자극 시간 정보
                    runtime = sprintf('%d (s)',app.RuntimeEditField.Value);
                elseif app.RuntimeDropDown.Value == "(min)"
                    runtime = sprintf('%s (min)',app.RuntimeEditField.Value);
                elseif app.RuntimeDropDown.Value == "(h)"
                    runtime = sprintf('%s (h)',app.RuntimeEditField.Value);
                end                
                frequency = app.FrequencyHzEditField.Value; % 자극 frequency 정보
                setNumber = app.NumberofSetsSpinner.Value; % 자극 세트수 정보

                % Get existing table data
                tableData = get(app.CheckerboardHistory, 'Data');

                % Append the new data to the existing table data
                newRow = {boardsize, runtime, frequency, setNumber}; % Elements compiled into new row
                newTableData = [tableData; newRow];

                % Update the table with the new data
                set(app.CheckerboardHistory, 'Data', newTableData);
            end
            %% StimInfo and Mat Setup
            StimInfo = {};
            %imageArray={};
            for i=1:rep
                RandMat=rand(n)<0.5; % Pseudo-RandomCheckerboard 자극생성
                RandMat=double(RandMat);
                RandMat = reshape(RandMat, 1, numSquares);
                RandMat = repmat(RandMat, 3, 1);
                %Screen('FillRect',window,RandMat,allRectsLeft); % Display the rectangle on the screen
                %Screen('Flip',window); % Display the rectangle on the screen
                %fwrite(s,0); % Sending a TTL pulse to MC_Card through RS232c
                %WaitSecs(visTime);  % Pause for visTime seconds
                StimInfo = [StimInfo; RandMat(1,:)];% Saving stimulus information in a matrix
                %imageArray = [imageArray; {Screen('GetImage', window, windowRect)}];% Produces a dynamic display and outputs an animated .gif record of the trial
                % Records the frame from front buffer and appends to the imageArray matrix
                % Assumes a screen resolution of 1024x768; alter the rect coordinates to change this
            end
            StimInfo = [StimInfo;StimInfo];
            Mat = {};
            %for k=1:rep
            for k=1:tot_rep
                Mat = [Mat; repmat(StimInfo{k}, 3, 1)];
            end

            %% Save StimInfo and Mat
            CurrentDate = datestr(datetime,'yyyymmdd HH_MM_SS'); % variable containing file name which is the current date
            Directories = {'Save Folder', 'Checkerboard Info', CurrentDate};
            for k = 1:length(Directories)
                thisDir = fullfile(Directories{1:k});
                mkdir(thisDir); % Makes new folder if 'Save Folder', 'Checkerboard Info', or CurrentDate Folder does not exist
            end
            StimInfoSave = StimInfo{1,1}; % variable containing matrix of first frame of checkerboard pattern in white(1) or black (0)
            MatSave = Mat{1,1}; % variable containing a 3xN matrix of first frame of checkerboard pattern in RGB
            
            % File title. Title will include the following:
            CBoardSize = (app.CheckerboardSizenxnSpinner.Value); % Checkerboard dimensions
            CBoardFreq = app.FrequencyHzEditField.Value; % Checkerboard frequency
            title=sprintf('%dx%d_%dHz',CBoardSize,CBoardSize,CBoardFreq);
            filePath = fullfile('Save Folder','Checkerboard Info', CurrentDate, [title, '.mat']); 
            save(filePath,'StimInfoSave','MatSave'); % File made and saved in .mat format
            %% Light stimulation
            %for j=1:rep
            for j=1:tot_rep
                drawnow;% Look for stopFlag = true
                if app.stopFlag == true % If STOP button pushed, loop will break and illumination will stop
                    break
                end
                Screen('FillRect',app.window,Mat{j},allRectsLeft); % Display the rectangle on the screen
                Screen('Flip',app.window); % Display the rectangle on the screen
                %if j==1
                % fwrite(s,0); % Sending a TTL pulse to MC_Card through RS232c
                %end
                WaitSecs(visTime); % Pause for visTime seconds
            end
            Screen('Flip',app.window);
        end

        % Button pushed function: DoubleClicktoSTOPButton
        function DoubleClicktoSTOPButtonPushed(app, event)
            app.stopFlag = true; % Stimulation will stop when stopFlag is set to true
        end

        % Button pushed function: SaveFullfieldProtocolButton
        function SaveFullfieldProtocolButtonPushed(app, event)
            Directories = {'Save Folder', 'Protocols', 'Full-field Illumination Protocols'};
            for k = 1:length(Directories)
                thisDir = fullfile(Directories{1:k});
                mkdir(thisDir); % Makes new folder if 'Save Folder', 'Protocols', or 'Full-field Illumination Protocols' Folder does not exist
            end            
            %%
            CurrentDate = datestr(datetime,'yyyymmdd'); % variable containing file name which is the current date

            dataToSave = app.FullfieldIlluminationHistory.Data;
            ProtocolFolder = fullfile('Save Folder','Protocols','Full-field Illumination Protocols');
            [fileName, filePath] = uiputfile('*.mat',sprintf('%s',CurrentDate),ProtocolFolder);
            if isequal(fileName, 0) || isequal(filePath, 0)
                % User canceled the save operation
                return;
            end
            fullfilePath = fullfile(filePath,fileName);
            save(fullfilePath, 'dataToSave')
        end

        % Button pushed function: LoadFullfieldProtocolButton
        function LoadFullfieldProtocolButtonPushed(app, event)
            ProtocolFolder = fullfile('Save Folder','Protocols','Full-field Illumination Protocols');
            [fileName, filePath] = uigetfile(ProtocolFolder);
            if isequal(fileName, 0) || isequal(filePath, 0)
                % User canceled the load operation
                return;
            end
            % Combine the file name and path
            fullFilePath = fullfile(filePath, fileName);

            % Load the data from the selected MAT file
            loadedData = load(fullFilePath);
            dataTable = loadedData.dataToSave;
            % Populate the table with the loaded data
            app.FullfieldIlluminationHistory.Data = dataTable;
        end

        % Button pushed function: SaveCheckerboardProtocolButton
        function SaveCheckerboardProtocolButtonPushed(app, event)
            Directories = {'Save Folder', 'Protocols', 'Checkerboard Protocols'};
            for k = 1:length(Directories)
                thisDir = fullfile(Directories{1:k});
                mkdir(thisDir); % Makes new folder if 'Save Folder', 'Protocols', or 'Checkerboard Protocols' Folder does not exist
            end            
            %% Things that may be useful in a title
            % Repetition = (app.CheckerboardSizenxnSpinner.Value);
            % IlluminationON = app.FrequencyHzEditField.Value;
            % IlluminationOFF
            % FullfieldColor
            % BackgroundColor
            %%
            CurrentDate = datestr(datetime,'yyyymmdd'); % variable containing file name which is the current date

            dataToSave = app.CheckerboardHistory.Data;
            ProtocolFolder = fullfile('Save Folder','Protocols','Checkerboard Protocols');
            [fileName, filePath] = uiputfile('*.mat', sprintf('%d Protocol',CurrentDate),ProtocolFolder);
            if isequal(fileName, 0) || isequal(filePath, 0)
                % User canceled the save operation
                return;
            end
            fullfilePath = fullfile(filePath,fileName);
            save(fullfilePath, 'dataToSave')
            % filePath = fullfile('Save Folder','Checkerboard', CurrentDate, [title, '.mat']);
            % save(filePath,'StimInfoSave','MatSave');
            % 
            % % Save the data to the selected file in JSON format
            % jsonStr = jsonencode(dataToSave);
            % fid = fopen(fullfilePath, 'w');
            % fwrite(fid, jsonStr);
            % fclose(fid)
        end

        % Button pushed function: LoadCheckerboardProtocolButton
        function LoadCheckerboardProtocolButtonPushed(app, event)
            ProtocolFolder = fullfile('Save Folder','Protocols','Checkerboard Protocols');
            [fileName, filePath] = uigetfile(ProtocolFolder);
            if isequal(fileName, 0) || isequal(filePath, 0)
                % User canceled the load operation
                return;
            end
            % Combine the file name and path
            fullFilePath = fullfile(filePath, fileName);

            % Load the data from the selected MAT file
            loadedData = load(fullFilePath);
            % Convert the cell array to a table
            dataTable = loadedData.dataToSave;
            % % Assign the loaded table to the dataTable variable
            % dataTable = loadedData;
            % Populate the table with the loaded data
            app.CheckerboardHistory.Data = dataTable;
        end

        % Button pushed function: ClearFullfieldHistoryButton
        function ClearFullfieldHistoryButtonPushed(app, event)
            app.FullfieldIlluminationHistory.Data = []; % clears table
        end

        % Button pushed function: ClearCheckerboardHistoryButton
        function ClearCheckerboardHistoryButtonPushed(app, event)
            app.CheckerboardHistory.Data = []; % clears table
        end

        % Menu selected function: DeleteRowMenu
        function DeleteRowMenuSelected(app, event)
            if app.selectedrow_fullfield == 99
                b=2
                 return
            else
                SelectedRow = app.selectedrow_fullfield(1,1);
                app.FullfieldIlluminationHistory.Data(SelectedRow, :) = [];
            end
        end

        % Cell selection callback: FullfieldIlluminationHistory
        function FullfieldIlluminationHistoryCellSelection(app, event)
            indices = event.Indices;
            app.selectedrow_fullfield = indices;    
        end

        % Key press function: FullfieldIlluminationHistory
        function FullfieldIlluminationHistoryKeyPress(app, event)
            % key = event.Key;
            % if key == delete
            %     a=55555
            % end
        end

        % Cell selection callback: CheckerboardHistory
        function CheckerboardHistoryCellSelection(app, event)
            indices = event.Indices;
            app.selectedrow_checkerboard = indices;
        end

        % Menu selected function: DeleteRowMenu_2
        function DeleteRowMenu_2Selected(app, event)
            SelectedRow = app.selectedrow_checkerboard(1,1);
            app.CheckerboardHistory.Data(SelectedRow, :) = [];
        end

        % Menu selected function: ApplySettingsMenu
        function ApplySettingsMenuSelected(app, event)
            SelectedRow = app.selectedrow_fullfield(1,1);
            
            app.FullfieldIlluminationRepetitionSpinner.Value = cell2mat(app.FullfieldIlluminationHistory.Data(SelectedRow,1));
            % Full-field illumination ON settings
            if contains(cell2mat(app.FullfieldIlluminationHistory.Data(SelectedRow,2)), "(s)")
                % Cell = table2array(app.FullfieldIlluminationHistory.Data(SelectedRow,2));
                Matrix = cell2mat(app.FullfieldIlluminationHistory.Data(SelectedRow,2));
                SplitMatrix = strsplit(Matrix, ' (s)');
                numExtract = sprintf('%d',str2double(SplitMatrix(1,1)));
                app.FullfieldIlluminationONEditField.Value = numExtract;
                app.FullfieldIlluminationONDropDown.Value = "(s)";
            elseif contains(cell2mat(app.FullfieldIlluminationHistory.Data(SelectedRow,2)), "(ms)")
                % Cell = table2array(app.FullfieldIlluminationHistory.Data(SelectedRow,2));
                Matrix = cell2mat(app.FullfieldIlluminationHistory.Data(SelectedRow,2));
                SplitMatrix = strsplit(Matrix, ' (ms)');
                numExtract = sprintf('%d',str2double(SplitMatrix(1,1)));
                app.FullfieldIlluminationONEditField.Value = numExtract;
                app.FullfieldIlluminationONDropDown.Value = "(ms)";
            elseif contains(cell2mat(app.FullfieldIlluminationHistory.Data(SelectedRow,2)), "(μs)")
                % Cell = table2array(app.FullfieldIlluminationHistory.Data(SelectedRow,2));
                Matrix = cell2mat(app.FullfieldIlluminationHistory.Data(SelectedRow,2));
                SplitMatrix = strsplit(Matrix, ' (μs)');
                numExtract = sprintf('%d',str2double(SplitMatrix(1,1)));
                app.FullfieldIlluminationONEditField.Value = numExtract;
                app.FullfieldIlluminationONDropDown.Value = "(μs)";
            end
            % Full-field illumination OFF settings
            if contains(cell2mat(app.FullfieldIlluminationHistory.Data(SelectedRow,3)), "(s)")
                % Cell = table2array(app.FullfieldIlluminationHistory.Data(SelectedRow,2));
                Matrix = cell2mat(app.FullfieldIlluminationHistory.Data(SelectedRow,3));
                SplitMatrix = strsplit(Matrix, ' (s)');
                numExtract = sprintf('%d',str2double(SplitMatrix(1,1)));
                app.FullfieldIlluminationOFFEditField.Value = numExtract;
                app.FullfieldIlluminationOFFDropDown.Value = "(s)";
            elseif contains(cell2mat(app.FullfieldIlluminationHistory.Data(SelectedRow,3)), "(ms)")
                % Cell = table2array(app.FullfieldIlluminationHistory.Data(SelectedRow,2));
                Matrix = cell2mat(app.FullfieldIlluminationHistory.Data(SelectedRow,3));
                SplitMatrix = strsplit(Matrix, ' (ms)');
                numExtract = sprintf('%d',str2double(SplitMatrix(1,1)));
                app.FullfieldIlluminationOFFEditField.Value = numExtract;
                app.FullfieldIlluminationOFFDropDown.Value = "(ms)";
            elseif contains(cell2mat(app.FullfieldIlluminationHistory.Data(SelectedRow,3)), "(μs)")
                % Cell = table2array(app.FullfieldIlluminationHistory.Data(SelectedRow,2));
                Matrix = cell2mat(app.FullfieldIlluminationHistory.Data(SelectedRow,3));
                SplitMatrix = strsplit(Matrix, ' (μs)');
                numExtract = sprintf('%d',str2double(SplitMatrix(1,1)));
                app.FullfieldIlluminationOFFEditField.Value = numExtract;
                app.FullfieldIlluminationOFFDropDown.Value = "(μs)";
            end
            app.FullfieldColorDropDown.Value = cell2mat(app.FullfieldIlluminationHistory.Data(SelectedRow,4));
            app.BackgroundColorDropDown.Value = cell2mat(app.FullfieldIlluminationHistory.Data(SelectedRow,5));
        end

        % Menu selected function: ApplySettingsMenu_2
        function ApplySettingsMenu_2Selected(app, event)
            SelectedRow = app.selectedrow_checkerboard(1,1);
            
            Matrix = cell2mat(app.CheckerboardHistory.Data(SelectedRow,1));
            SplitMatrix = strsplit(Matrix,'x');
            numExtract = str2double(SplitMatrix(1,1));
            app.CheckerboardSizenxnSpinner.Value  = numExtract;
            % Full-field illumination ON settings
            if contains(cell2mat(app.CheckerboardHistory.Data(SelectedRow,2)), "(s)")
                % Cell = table2array(app.FullfieldIlluminationHistory.Data(SelectedRow,2));
                Matrix = cell2mat(app.CheckerboardHistory.Data(SelectedRow,2));
                SplitMatrix = strsplit(Matrix, ' (s)');
                numExtract = str2double(SplitMatrix(1,1));
                app.RuntimeEditField.Value = numExtract;
                app.RuntimeDropDown.Value = "(s)";
            elseif contains(cell2mat(app.CheckerboardHistory.Data(SelectedRow,2)), "(min)")
                % Cell = table2array(app.FullfieldIlluminationHistory.Data(SelectedRow,2));
                Matrix = cell2mat(app.CheckerboardHistory.Data(SelectedRow,2));
                SplitMatrix = strsplit(Matrix, ' (min)');
                numExtract = str2double(SplitMatrix(1,1));
                app.RuntimeEditField.Value = numExtract;
                app.RuntimeDropDown.Value = "(min)";
            elseif contains(cell2mat(app.CheckerboardHistory.Data(SelectedRow,2)), "(h)")
                % Cell = table2array(app.FullfieldIlluminationHistory.Data(SelectedRow,2));
                Matrix = cell2mat(app.CheckerboardHistory.Data(SelectedRow,2));
                SplitMatrix = strsplit(Matrix, ' (h)');
                numExtract = str2double(SplitMatrix(1,1));
                app.RuntimeEditField.Value = numExtract;
                app.RuntimeDropDown.Value = "(h)";
            end
            app.FrequencyHzEditField.Value = cell2mat(app.CheckerboardHistory.Data(SelectedRow,3));
            app.NumberofSetsSpinner.Value = cell2mat(app.CheckerboardHistory.Data(SelectedRow,4));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OpenWindowButton = uibutton(app.UIFigure, 'push');
            app.OpenWindowButton.ButtonPushedFcn = createCallbackFcn(app, @OpenWindowButtonPushed, true);
            app.OpenWindowButton.Text = 'Open Window';
            app.RunFullfieldIlluminationButton = uibutton(app.UIFigure, 'push');
            app.RunFullfieldIlluminationButton.ButtonPushedFcn = createCallbackFcn(app, @RunFullfieldIlluminationButtonPushed, true);
            app.RunFullfieldIlluminationButton.Text = 'Run Full-field Illumination';
            app.FullfieldIlluminationRepetitionSpinnerLabel = uilabel(app.UIFigure);
            app.FullfieldIlluminationRepetitionSpinnerLabel.Text = 'Full-field Illumination Repetition';
            app.FullfieldIlluminationRepetitionSpinner = uispinner(app.UIFigure);
            app.FullfieldIlluminationRepetitionSpinner.Value = 10;
            app.FullfieldIlluminationONEditFieldLabel = uilabel(app.UIFigure);
            app.FullfieldIlluminationONEditFieldLabel.Text = 'Full-field Illumination ON ';
            app.FullfieldIlluminationONEditField = uieditfield(app.UIFigure, 'text');
            app.FullfieldIlluminationONEditField.Value = '4';
            app.FullfieldIlluminationOFFEditFieldLabel = uilabel(app.UIFigure);
            app.FullfieldIlluminationOFFEditFieldLabel.Text = 'Full-field Illumination OFF';
            app.FullfieldIlluminationOFFEditField = uieditfield(app.UIFigure, 'text');
            app.FullfieldIlluminationOFFEditField.Value = '4';
            app.FullfieldColorDropDownLabel = uilabel(app.UIFigure);
            app.FullfieldColorDropDownLabel.Text = 'Full-field Color';
            app.FullfieldColorDropDown = uidropdown(app.UIFigure);
            app.FullfieldColorDropDown.Items = {'White', 'Black', 'Red', 'Green', 'Blue'};
            app.FullfieldColorDropDown.Value = 'White';
            app.RunRandomCheckerboardIlluminationButton = uibutton(app.UIFigure, 'push');
            app.RunRandomCheckerboardIlluminationButton.ButtonPushedFcn = createCallbackFcn(app, @RunRandomCheckerboardIlluminationButtonPushed, true);
            app.RunRandomCheckerboardIlluminationButton.Text = 'Run Random Checkerboard Illumination';
            app.RuntimeEditFieldLabel = uilabel(app.UIFigure);
            app.RuntimeEditFieldLabel.Text = 'Runtime ';
            app.RuntimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.RuntimeEditField.Value = 1;
            app.FrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyHzEditField.Value = 10;
            app.NumberofSetsSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofSetsSpinnerLabel.Text = 'Number of Sets';
            app.NumberofSetsSpinner = uispinner(app.UIFigure);
            app.NumberofSetsSpinner.Value = 1;
            app.CheckerboardHistory = uitable(app.UIFigure);
            app.CheckerboardHistory.ColumnName = {'Board Size'; 'Runtime'; 'Frequency'; 'Number of Sets'};
            app.CheckerboardHistory.RowName = {};
            app.CheckerboardHistory.SelectionType = 'row';
            app.CheckerboardHistory.CellSelectionCallback = createCallbackFcn(app, @CheckerboardHistoryCellSelection, true);
            app.FullfieldIlluminationHistory = uitable(app.UIFigure);
            app.FullfieldIlluminationHistory.ColumnName = {'Repetition'; 'Time ON'; 'Time OFF'; 'Color'; 'Background'};
            app.FullfieldIlluminationHistory.RowName = {};
            app.FullfieldIlluminationHistory.SelectionType = 'row';
            app.FullfieldIlluminationHistory.CellSelectionCallback = createCallbackFcn(app, @FullfieldIlluminationHistoryCellSelection, true);
            app.FullfieldIlluminationHistory.KeyPressFcn = createCallbackFcn(app, @FullfieldIlluminationHistoryKeyPress, true);
            app.CheckerboardSizenxnSpinnerLabel = uilabel(app.UIFigure);
            app.CheckerboardSizenxnSpinnerLabel.Text = 'Checkerboard Size (nxn)';
            app.CheckerboardSizenxnSpinner = uispinner(app.UIFigure);
            app.CheckerboardSizenxnSpinner.Value = 8;
            app.DoubleClicktoSTOPButton = uibutton(app.UIFigure, 'push');
            app.DoubleClicktoSTOPButton.ButtonPushedFcn = createCallbackFcn(app, @DoubleClicktoSTOPButtonPushed, true);
            app.DoubleClicktoSTOPButton.Text = 'Double Click to STOP';
            app.FullfieldIlluminationONDropDown = uidropdown(app.UIFigure);
            app.FullfieldIlluminationONDropDown.Items = {'(s)', '(ms)', '(μs)'};
            app.FullfieldIlluminationONDropDown.Value = '(s)';
            app.FullfieldIlluminationOFFDropDown = uidropdown(app.UIFigure);
            app.FullfieldIlluminationOFFDropDown.Items = {'(s)', '(ms)', '(μs)'};
            app.FullfieldIlluminationOFFDropDown.Value = '(s)';
            app.RuntimeDropDown = uidropdown(app.UIFigure);
            app.RuntimeDropDown.Items = {'(s)', '(min)', '(h)'};
            app.RuntimeDropDown.Value = '(s)';
            app.BackgroundColorDropDownLabel = uilabel(app.UIFigure);
            app.BackgroundColorDropDownLabel.Text = 'Background Color';
            app.BackgroundColorDropDown = uidropdown(app.UIFigure);
            app.BackgroundColorDropDown.Items = {'Black', 'White', 'Red', 'Green', 'Blue'};
            app.BackgroundColorDropDown.Value = 'Black';
            app.RecordInputHistoryLabel = uilabel(app.UIFigure);
            app.RecordInputHistoryLabel.Text = {'Record Input '; 'History'};
            app.RecordFullfieldHistorySwitch = uiswitch(app.UIFigure, 'slider');
            app.RecordFullfieldHistorySwitch.Value = 'On';
            app.SaveFullfieldProtocolButton = uibutton(app.UIFigure, 'push');
            app.SaveFullfieldProtocolButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFullfieldProtocolButtonPushed, true);
            app.SaveFullfieldProtocolButton.Text = 'Save Protocol';
            app.ClearFullfieldHistoryButton = uibutton(app.UIFigure, 'push');
            app.ClearFullfieldHistoryButton.ButtonPushedFcn = createCallbackFcn(app, @ClearFullfieldHistoryButtonPushed, true);
            app.ClearFullfieldHistoryButton.Text = 'Clear Input History';
            app.LoadFullfieldProtocolButton = uibutton(app.UIFigure, 'push');
            app.LoadFullfieldProtocolButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFullfieldProtocolButtonPushed, true);
            app.LoadFullfieldProtocolButton.Text = 'Load Protocol';
            app.RecordInputHistoryLabel_2 = uilabel(app.UIFigure);
            app.RecordInputHistoryLabel_2.Text = {'Record Input '; 'History'};
            app.RecordCheckerboardHistorySwitch = uiswitch(app.UIFigure, 'slider');
            app.RecordCheckerboardHistorySwitch.Value = 'On';
            app.SaveCheckerboardProtocolButton = uibutton(app.UIFigure, 'push');
            app.SaveCheckerboardProtocolButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCheckerboardProtocolButtonPushed, true);
            app.SaveCheckerboardProtocolButton.Text = 'Save Protocol';
            app.LoadCheckerboardProtocolButton = uibutton(app.UIFigure, 'push');
            app.LoadCheckerboardProtocolButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCheckerboardProtocolButtonPushed, true);
            app.LoadCheckerboardProtocolButton.Text = 'Load Protocol';
            app.ClearCheckerboardHistoryButton = uibutton(app.UIFigure, 'push');
            app.ClearCheckerboardHistoryButton.ButtonPushedFcn = createCallbackFcn(app, @ClearCheckerboardHistoryButtonPushed, true);
            app.ClearCheckerboardHistoryButton.Text = 'Clear Input History';
            app.FullfieldIlluminationLabel = uilabel(app.UIFigure);
            app.FullfieldIlluminationLabel.Text = 'Full-field Illumination';
            app.RandomCheckerboardIlluminationLabel = uilabel(app.UIFigure);
            app.RandomCheckerboardIlluminationLabel.Text = 'Random Checkerboard Illumination';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ApplySettingsMenu = uimenu(app.ContextMenu);
            app.ApplySettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @ApplySettingsMenuSelected, true);
            app.ApplySettingsMenu.Text = 'Apply Settings';
            app.DeleteRowMenu = uimenu(app.ContextMenu);
            app.DeleteRowMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteRowMenuSelected, true);
            app.DeleteRowMenu.Text = 'Delete Row';
            app.AddRowMenu = uimenu(app.ContextMenu);
            app.AddRowMenu.Text = 'Add Row';
            % Assign app.ContextMenu
            app.FullfieldIlluminationHistory.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.ApplySettingsMenu_2 = uimenu(app.ContextMenu2);
            app.ApplySettingsMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ApplySettingsMenu_2Selected, true);
            app.ApplySettingsMenu_2.Text = 'Apply Settings';
            app.DeleteRowMenu_2 = uimenu(app.ContextMenu2);
            app.DeleteRowMenu_2.MenuSelectedFcn = createCallbackFcn(app, @DeleteRowMenu_2Selected, true);
            app.DeleteRowMenu_2.Text = 'Delete Row';
            app.AddRowMenu_2 = uimenu(app.ContextMenu2);
            app.AddRowMenu_2.Text = 'Add Row';
            % Assign app.ContextMenu2
            app.CheckerboardHistory.ContextMenu = app.ContextMenu2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LightStim_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end